classdef recieve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PRESSTOSTARTRECORDINGButton  matlab.ui.control.Button
        ENTERENCRYPTIONKEYEditFieldLabel  matlab.ui.control.Label
        ENTERENCRYPTIONKEYEditField  matlab.ui.control.NumericEditField
        OUTPUTMESSAGEEditFieldLabel  matlab.ui.control.Label
        OUTPUTMESSAGEEditField       matlab.ui.control.EditField
        STATUSEditFieldLabel         matlab.ui.control.Label
        STATUSEditField              matlab.ui.control.EditField
        starttimeEditFieldLabel      matlab.ui.control.Label
        starttimeEditField           matlab.ui.control.NumericEditField
        endtimeEditFieldLabel        matlab.ui.control.Label
        endtimeEditField             matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PRESSTOSTARTRECORDINGButton
        function PRESSTOSTARTRECORDINGButtonPushed(app, event)
fs=44100;  % sampling frequency
Fs = 44100 ; 
nBits = 16 ; 
nChannels = 1 ; 
ID = -1; % default audio input device 
recObj = audiorecorder(Fs,nBits,nChannels,ID);
app.STATUSEditField.Value="RECORDING";
recordblocking(recObj,10);
app.STATUSEditField.Value="NOT RECORDING";
x = getaudiodata(recObj);
y=bandpass(x,[1999 2001],fs);%identify the starting beacon bit
w=bandpass(x,[2999 3001],fs);%identify the trailing beacon bit
t=1:length(x);
m=mean(abs(y));
%m1=mean(abs(w));
start_time=max(find((abs(y)>50*m)==1))/Fs;%identify start time from filtered signal
end_time=min(find((abs(w)>50*m)==1))/Fs;%identify end time from filtered signal
app.starttimeEditField.Value=start_time;
app.endtimeEditField.Value=end_time;
%% 
%############################################applying filter banks#######################################################
m=mean(abs(y))
start_sample=max(find((abs(y)>20*m)==1))%get start sample
start_time=max(find((abs(y)>20*m)==1))/Fs
hfreq=[1209,1336,1477,1633];
lfreq=[697,770,852,941];
window_freqs=[];
winlength=0.25;
for i=start_sample:winlength*Fs:end_time*Fs-winlength
    try
%applying filters to check for all DTMF frequencies
y1209=bandpass(x(i:i+winlength*Fs),[1208 1210],fs);
y1336=bandpass(x(i:i+winlength*Fs),[1335 1337],fs);
y1477=bandpass(x(i:i+winlength*Fs),[1477 1479],fs);
y1633=bandpass(x(i:i+winlength*Fs),[1632 1634],fs);
y697=bandpass(x(i:i+winlength*Fs),[696 698],fs);
y770=bandpass(x(i:i+winlength*Fs),[769 771],fs);
y852=bandpass(x(i:i+winlength*Fs),[851 853],fs);
y941=bandpass(x(i:i+winlength*Fs),[940 942],fs);
%determining which frequencies were present
[M,Ih]=max([mean(abs(y1209)) mean(abs(y1336)) mean(abs(y1477)) mean(abs(y1633))]);
[M,Il]=max([mean(abs(y697)) mean(abs(y770)) mean(abs(y852)) mean(abs(y941))]);
window_freqs=[window_freqs;hfreq(Ih),lfreq(Il)];
    end
end
%% 
%############################################Decrypting message####################################################### 
key=app.ENTERENCRYPTIONKEYEditField.Value;
hfreq=[1209,1336,1477,1633];
rng(key)
hfreq=hfreq(randperm(length(hfreq)));
lfreq=[697,770,852,941];
lfreq=lfreq(randperm(length(lfreq)));
hexnos=['0','1','2','3','4','5','6','7','8','9','A','B','C','D','E','F'];
freqs=[hfreq(1),lfreq(1);hfreq(1),lfreq(2);hfreq(1),lfreq(3);hfreq(1),lfreq(4);hfreq(2),lfreq(1);hfreq(2),lfreq(2);hfreq(2),lfreq(3);hfreq(2),lfreq(4);hfreq(3),lfreq(1);hfreq(3),lfreq(2);hfreq(3),lfreq(3);hfreq(3),lfreq(4);hfreq(4),lfreq(1);hfreq(4),lfreq(2);hfreq(4),lfreq(3);hfreq(4),lfreq(4)];
freqs;
hexMessage="";
for i =1:length(window_freqs)
%hexnos(find(sum(window_freqs(i,:)==freqs,2)==2))
hexMessage=hexMessage+hexnos(find(sum(window_freqs(i,:)==freqs,2)==2));
if mod(i,2)==0
    hexMessage=hexMessage+" ";
end
end
hexMessage=split(hexMessage);
number = hex2dec(hexMessage);
app.OUTPUTMESSAGEEditField.Value= convertCharsToStrings(native2unicode(number,'utf-8')');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PRESSTOSTARTRECORDINGButton = uibutton(app.UIFigure, 'push');
            app.PRESSTOSTARTRECORDINGButton.ButtonPushedFcn = createCallbackFcn(app, @PRESSTOSTARTRECORDINGButtonPushed, true);
            app.PRESSTOSTARTRECORDINGButton.Text = 'PRESS TO START RECORDING';
            app.ENTERENCRYPTIONKEYEditFieldLabel = uilabel(app.UIFigure);
            app.ENTERENCRYPTIONKEYEditFieldLabel.Text = 'ENTER ENCRYPTION KEY';
            app.ENTERENCRYPTIONKEYEditField = uieditfield(app.UIFigure, 'numeric');
            app.OUTPUTMESSAGEEditFieldLabel = uilabel(app.UIFigure);
            app.OUTPUTMESSAGEEditFieldLabel.Text = 'OUTPUT MESSAGE';
            app.OUTPUTMESSAGEEditField = uieditfield(app.UIFigure, 'text');
            app.STATUSEditFieldLabel = uilabel(app.UIFigure);
            app.STATUSEditFieldLabel.Text = 'STATUS';
            app.STATUSEditField = uieditfield(app.UIFigure, 'text');
            app.STATUSEditField.Value = 'NOT RECORDING';
            app.starttimeEditFieldLabel = uilabel(app.UIFigure);
            app.starttimeEditFieldLabel.Text = 'start time';
            app.starttimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.endtimeEditFieldLabel = uilabel(app.UIFigure);
            app.endtimeEditFieldLabel.Text = 'end time';
            app.endtimeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recieve

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end